classdef converter_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConverterUIFigure               matlab.ui.Figure
        InputButton                     matlab.ui.control.Button
        OutputButton                    matlab.ui.control.Button
        InputField                      matlab.ui.control.EditField
        OutputField                     matlab.ui.control.EditField
        FeedbackArea                    matlab.ui.control.TextArea
        OutputfoldernameEditFieldLabel  matlab.ui.control.Label
        OutputNameField                 matlab.ui.control.EditField
        ConvertButton                   matlab.ui.control.Button
        InputFileTypeDropDownLabel      matlab.ui.control.Label
        InputFileTypeDropDown           matlab.ui.control.DropDown
    end


    properties (Access = public)
        % Link to EEGsonic_app
        main_application;
    end

    properties (Access = private) 
        % Input and Output structures
        input;
        output;
    end

    methods (Access = private)
        % Write a message to the feedback box
        function write_to_feedback(app,message)
            app.FeedbackArea.Value = message;
        end
        
    end


    methods (Access = private)

        % Close request function: ConverterUIFigure
        function ConverterUIFigureCloseRequest(app, event)
            app.main_application.is_converter_running = 0;
            delete(app)
        end

        % Button pushed function: InputButton
        function InputButtonPushed(app, event)
            % Get type to look for
            app.input.type = app.InputFileTypeDropDown.Value;
            filter = strcat("*",".",app.input.type);
            
            % Get a input file
            [file,path] = uigetfile(filter);
            app.input.file = file;
            app.input.path = path;
            
            % Check if file is valid
            if(file ~= 0)
                app.InputField.Value = file;
                write_to_feedback(app,'Input file selected');
            else
                app.InputField.Value = "No input file";
            end
        end

        % Button pushed function: OutputButton
        function OutputButtonPushed(app, event)
            % Get a output directory
            app.output = uigetdir();
            
            % Check if directory is valid
            if(app.output ~= 0)
                app.OutputField.Value = app.output;
                write_to_feedback(app,'Output folder selected');
            else
                app.OutputField.Value = 'No output folder';
            end
        end

        % Button pushed function: ConvertButton
        function ConvertButtonPushed(app, event)
            % Prepare the output and input paths
            output_name = strtrim(app.OutputNameField.Value);
            output_path = strcat(app.output,filesep,output_name);
            input_path = strcat(app.input.path,filesep,app.input.file);
            
            % Error checking for the output name and path
            if(strcmp(output_name,""))
                write_to_feedback(app,'Write the output name');
            elseif(exist(output_path,'dir'))
                write_to_feedback(app,'An output folder with same name already exist: ' + output_path);
            elseif(isfile(input_path) && exist(app.output,'dir'))
                % Load the app data
                base_directory = erase(mfilename('fullpath'),strcat(filesep,mfilename));
                data = load(strcat(base_directory,filesep,'..',filesep,'data',filesep,'app_data.mat'));
                app_data = struct();
                app_data.parameters = data.parameters;
                app_data.information = data.information;
                % Create the directory structure
                create_dir_structure(output_path);
                % Convert the File into a format readable by EEGsonic
                [sampling_rate,number_channels,channels_location,channels_info] = convert(output_path,app.input.path,app.input.file,app.input.type);
                
                % Save app_data
                app_data.parameters.general.egi129.sampling_rate = sampling_rate;
                app_data.information.headset.egi129.number_channels = number_channels;
                app_data.information.headset.egi129.channels_location = channels_location;
                app_data.information.headset.egi129.channels_info = channels_info;
                
                % Edit the paths to reflect where we will be saving
                app_data.information.replay_directory = output_path;
                app_data.information.data_directory = strcat(output_path,filesep,'data');
                app_data.information.parameters_directory = strcat(output_path,filesep,'parameters');
                app_data.information.features_directory = strcat(output_path, filesep, 'features');
                parameters_path = strcat(output_path,filesep,'parameters');
                app_data_path = strcat(parameters_path,filesep,'app_data.mat');
                save(app_data_path,'app_data');
                write_to_feedback(app,'Finished converting');
            else
                write_to_feedback(app,'input or output is not right');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ConverterUIFigure = uifigure;
            app.ConverterUIFigure.Name = 'Converter';
            app.ConverterUIFigure.CloseRequestFcn = createCallbackFcn(app, @ConverterUIFigureCloseRequest, true);
            app.InputButton = uibutton(app.ConverterUIFigure, 'push');
            app.InputButton.ButtonPushedFcn = createCallbackFcn(app, @InputButtonPushed, true);
            app.InputButton.Text = 'Select input file';
            app.OutputButton = uibutton(app.ConverterUIFigure, 'push');
            app.OutputButton.ButtonPushedFcn = createCallbackFcn(app, @OutputButtonPushed, true);
            app.OutputButton.Text = 'Select output folder';
            app.InputField = uieditfield(app.ConverterUIFigure, 'text');
            app.InputField.Editable = 'off';
            app.InputField.Value = 'No input file selected';
            app.OutputField = uieditfield(app.ConverterUIFigure, 'text');
            app.OutputField.Editable = 'off';
            app.OutputField.Value = 'No output folder';
            app.FeedbackArea = uitextarea(app.ConverterUIFigure);
            app.FeedbackArea.Editable = 'off';
            app.FeedbackArea.Value = {'Feedback'; ''};
            app.OutputfoldernameEditFieldLabel = uilabel(app.ConverterUIFigure);
            app.OutputfoldernameEditFieldLabel.Text = 'Output folder name';
            app.OutputNameField = uieditfield(app.ConverterUIFigure, 'text');
            app.ConvertButton = uibutton(app.ConverterUIFigure, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Text = 'Convert';
            app.InputFileTypeDropDownLabel = uilabel(app.ConverterUIFigure);
            app.InputFileTypeDropDownLabel.Text = 'Input File Type';
            app.InputFileTypeDropDown = uidropdown(app.ConverterUIFigure);
            app.InputFileTypeDropDown.Items = {'set'};
            app.InputFileTypeDropDown.Value = 'set';
        end
    end

    methods (Access = public)

        % Construct app
        function app = converter_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ConverterUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConverterUIFigure)
        end
    end
end