classdef documentation_viewer_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DocumentationViewer         matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        HomeTab                     matlab.ui.container.Tab
        HomeTextArea                matlab.ui.control.TextArea
        GeneralTab                  matlab.ui.container.Tab
        GeneralTextArea             matlab.ui.control.TextArea
        OSCReceiversTab             matlab.ui.container.Tab
        OSCTextArea                 matlab.ui.control.TextArea
        SpectralPowerRatioTab       matlab.ui.container.Tab
        SPRTextArea                 matlab.ui.control.TextArea
        TopographicDistributionTab  matlab.ui.container.Tab
        TDTextArea                  matlab.ui.control.TextArea
        PhaseAmplitudeCouplingTab   matlab.ui.container.Tab
        PACTextArea                 matlab.ui.control.TextArea
        FrontoparietalwPLITab       matlab.ui.container.Tab
        FPwPLITextArea              matlab.ui.control.TextArea
        FrontoparietaldPLITab       matlab.ui.container.Tab
        FPdPLITextArea              matlab.ui.control.TextArea
        HubLocationTab              matlab.ui.container.Tab
        HLTextArea                  matlab.ui.control.TextArea
        PermutationEntropyTab       matlab.ui.container.Tab
        PETextArea                  matlab.ui.control.TextArea
    end


    properties (Access = public)
        main_application; % link to EEGsonic_app
    end

    properties (Access = private)
        % Structure containing paths
        doc;
    end

    methods (Access = private)
    
        function load_documentation(app)
            
            % Load Home Doc
            home_file_path = strcat(app.doc.directory,filesep,app.doc.home);
            home_text = load_text_file(home_file_path);
            app.HomeTextArea.Value = home_text;
            
            % Load General Doc
            general_file_path = strcat(app.doc.directory,filesep,app.doc.general);
            general_text = load_text_file(general_file_path);
            app.GeneralTextArea.Value = general_text;            
            
            % Load OSC Doc
            osc_file_path = strcat(app.doc.directory,filesep,app.doc.osc);
            osc_text = load_text_file(osc_file_path);
            app.OSCTextArea.Value = osc_text;            
            
            % Load spr Doc
            spr_file_path = strcat(app.doc.directory,filesep,app.doc.spr);
            spr_text = load_text_file(spr_file_path);
            app.SPRTextArea.Value = spr_text;            
            
            % Load td Doc
            td_file_path = strcat(app.doc.directory,filesep,app.doc.td);
            td_text = load_text_file(td_file_path);
            app.TDTextArea.Value = td_text;
            
            % Load pac Doc
            pac_file_path = strcat(app.doc.directory,filesep,app.doc.pac);
            pac_text = load_text_file(pac_file_path);
            app.PACTextArea.Value = pac_text;
            
            % Load fp_wpli Doc
            fp_wpli_file_path = strcat(app.doc.directory,filesep,app.doc.fp_wpli);
            fp_wpli_text = load_text_file(fp_wpli_file_path);
            app.FPwPLITextArea.Value = fp_wpli_text;
            
            % Load fp_dpli Doc
            fp_dpli_file_path = strcat(app.doc.directory,filesep,app.doc.fp_dpli);
            fp_dpli_text = load_text_file(fp_dpli_file_path);
            app.FPdPLITextArea.Value = fp_dpli_text;            
            
            % Load hl Doc
            hl_file_path = strcat(app.doc.directory,filesep,app.doc.hl);
            hl_text = load_text_file(hl_file_path);
            app.HLTextArea.Value = hl_text;
            
            % Load pe Doc
            pe_file_path = strcat(app.doc.directory,filesep,app.doc.pe);
            pe_text = load_text_file(pe_file_path);
            app.PETextArea.Value = pe_text;
            
        end
        
        function tab = get_tab(app,tab_name)
            if(strcmp(tab_name,"Home"))
                tab = app.HomeTab;
            elseif(strcmp(tab_name,"General"))
                tab = app.GeneralTab;
                
            elseif(strcmp(tab_name,"OSC Receivers"))
                tab = app.OSCReceiversTab;
                
            elseif(strcmp(tab_name,"Spectral Power Ratio"))
                tab = app.SpectralPowerRatioTab;
                
            elseif(strcmp(tab_name,"Topographic Distribution"))
                tab = app.TopographicDistributionTab;
                
            elseif(strcmp(tab_name,"Phase Amplitude Coupling"))
                tab = app.PhaseAmplitudeCouplingTab;
                
            elseif(strcmp(tab_name,"Frontoparietal wPLI"))
                tab = app.FrontoparietalwPLITab;
                
            elseif(strcmp(tab_name,"Frontoparietal dPLI"))
                tab = app.FrontoparietaldPLITab;
                
            elseif(strcmp(tab_name,"Hub Location"))
                tab = app.HubLocationTab;
                
            elseif(strcmp(tab_name,"Permutation Entropy"))
                tab = app.PermutationEntropyTab;
                
            end
        end
        
    end

    methods (Access = public)
    
        function select_tab(app,tab_name)
            app.TabGroup.SelectedTab = get_tab(app,tab_name);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.doc = struct();
            % Setup Base Directory
            base_directory = erase(mfilename('fullpath'),strcat(filesep,mfilename));
            
            % Setup Doc Directory
            app.doc.directory = strcat(base_directory,strcat(filesep,'..',filesep,'..',filesep,'doc'));
            
            % Setup the file name in the structure
            app.doc.home = "home_documentation.txt";
            app.doc.general = "general_documentation.txt";
            app.doc.osc = "osc_documentation.txt";
            app.doc.spr = "spr_documentation.txt";
            app.doc.td = "td_documentation.txt";
            app.doc.pac = "pac_documentation.txt";
            app.doc.fp_wpli = "fp_wpli_documentation.txt";
            app.doc.fp_dpli = "fp_dpli_documentation.txt";
            app.doc.hl = "hl_documentation.txt";
            app.doc.pe = "pe_documentation.txt";
            
            load_documentation(app);
        end

        % Close request function: DocumentationViewer
        function DocumentationViewerCloseRequest(app, event)
            app.main_application.is_documentation_viewer_running = 0;
            delete(app)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DocumentationViewer = uifigure;
            app.DocumentationViewer.Name = 'Documentation Viewer';
            app.DocumentationViewer.CloseRequestFcn = createCallbackFcn(app, @DocumentationViewerCloseRequest, true);
            app.TabGroup = uitabgroup(app.DocumentationViewer);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.HomeTextArea = uitextarea(app.HomeTab);
            app.HomeTextArea.Editable = 'off';
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.GeneralTextArea = uitextarea(app.GeneralTab);
            app.GeneralTextArea.Editable = 'off';
            app.OSCReceiversTab = uitab(app.TabGroup);
            app.OSCReceiversTab.Title = 'OSC Receivers';
            app.OSCTextArea = uitextarea(app.OSCReceiversTab);
            app.OSCTextArea.Editable = 'off';
            app.SpectralPowerRatioTab = uitab(app.TabGroup);
            app.SpectralPowerRatioTab.Title = 'Spectral Power Ratio';
            app.SPRTextArea = uitextarea(app.SpectralPowerRatioTab);
            app.SPRTextArea.Editable = 'off';
            app.TopographicDistributionTab = uitab(app.TabGroup);
            app.TopographicDistributionTab.Title = 'Topographic Distribution';
            app.TDTextArea = uitextarea(app.TopographicDistributionTab);
            app.TDTextArea.Editable = 'off';
            app.PhaseAmplitudeCouplingTab = uitab(app.TabGroup);
            app.PhaseAmplitudeCouplingTab.Title = 'Phase Amplitude Coupling';
            app.PACTextArea = uitextarea(app.PhaseAmplitudeCouplingTab);
            app.PACTextArea.Editable = 'off';
            app.FrontoparietalwPLITab = uitab(app.TabGroup);
            app.FrontoparietalwPLITab.Title = 'Frontoparietal wPLI';
            app.FPwPLITextArea = uitextarea(app.FrontoparietalwPLITab);
            app.FPwPLITextArea.Editable = 'off';
            app.FrontoparietaldPLITab = uitab(app.TabGroup);
            app.FrontoparietaldPLITab.Title = 'Frontoparietal dPLI';
            app.FPdPLITextArea = uitextarea(app.FrontoparietaldPLITab);
            app.FPdPLITextArea.Editable = 'off';
            app.HubLocationTab = uitab(app.TabGroup);
            app.HubLocationTab.Title = 'Hub Location';
            app.HLTextArea = uitextarea(app.HubLocationTab);
            app.HLTextArea.Editable = 'off';
            app.PermutationEntropyTab = uitab(app.TabGroup);
            app.PermutationEntropyTab.Title = 'Permutation Entropy';
            app.PETextArea = uitextarea(app.PermutationEntropyTab);
            app.PETextArea.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = documentation_viewer_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DocumentationViewer)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DocumentationViewer)
        end
    end
end