classdef headset_viewer_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HeadsetViewerUIFigure  matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        EGI129Tab              matlab.ui.container.Tab
        EGI129TextArea         matlab.ui.control.TextArea
        EGI129Button           matlab.ui.control.StateButton
        DSI24Tab               matlab.ui.container.Tab
        DSI24Button            matlab.ui.control.StateButton
        DSI24TextArea          matlab.ui.control.TextArea
    end


    properties (Access = public)
        % link to EEGsonic_app
        main_application; 
        
        % handles to the figures created by the app
        figure_egi129;
        figure_dsi24;
    end

    properties (Access = private)
        % information loaded from app_data
        information;
    end

    methods (Access = private)
    
        function update_gui(app)
            %% EGI129 Panel 
            egi129 = app.information.headset.egi129;
            
            % Text Area
            number_channels_string = "Number channels: " + num2str(egi129.number_channels);
            sampling_rate_string = "Sampling rate (Hz): " + num2str(egi129.sampling_rate);
            egi129_specs = number_channels_string + newline + sampling_rate_string;
            app.EGI129TextArea.Value = egi129_specs;
            
            %% DSI24 Panel
            dsi24 = app.information.headset.dsi24;
            
            % Text Area
            number_channels_string = "Number channels: " + num2str(dsi24.number_channels);
            sampling_rate_string = "Sampling rate (Hz): " + num2str(dsi24.sampling_rate);
            dsi24_specs = number_channels_string + newline + sampling_rate_string;
            app.DSI24TextArea.Value = dsi24_specs;
            
        end
        
        function create_plot(app,type,headset)
            if(strcmp(type,'egi129'))
                app.figure_egi129 = figure('visible','on');
                topoplot([],headset.channels_location,'style','blank','electrodes','labelpoint','chaninfo',headset.channels_info);
            elseif(strcmp(type,'dsi24'))
                app.figure_dsi24 = figure('visible','on');
                topoplot([],headset.channels_location,'style','blank','electrodes','labelpoint','chaninfo',headset.channels_info);                
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Setup
            base_directory = erase(mfilename('fullpath'),strcat(filesep,mfilename));
            
            % Setting up Variables
            app_data = load(strcat(base_directory,strcat(filesep,'..',filesep,'data',filesep,'app_data')));
            app.information = app_data.information;        
            
            % Update the GUI
            update_gui(app);
        end

        % Close request function: HeadsetViewerUIFigure
        function HeadsetViewerUIFigureCloseRequest(app, event)
            app.main_application.is_headset_viewer_running = 0;
            
            % Deleting the figures before exiting the app
            delete(app.figure_egi129);
            delete(app.figure_dsi24);
            delete(app)
        end

        % Value changed function: EGI129Button
        function EGI129ButtonValueChanged(app, event)
            value = app.EGI129Button.Value;
            % Depending on the value of the button we either create or
            % delete a figure.
            if(value == 1)
                create_plot(app,'egi129',app.information.headset.egi129);
                app.EGI129Button.Text = 'Hide Headset';
            else
                delete(app.figure_egi129);
                app.EGI129Button.Text = 'Plot Headset';                
            end
        end

        % Value changed function: DSI24Button
        function DSI24ButtonValueChanged(app, event)
            value = app.DSI24Button.Value;
            % Depending on the value of the button we either create or
            % delete a figure.
            if(value == 1)
                create_plot(app,'dsi24',app.information.headset.dsi24);
                app.DSI24Button.Text = 'Hide Headset';
            else
                delete(app.figure_dsi24);
                app.DSI24Button.Text = 'Plot Headset';
            end            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HeadsetViewerUIFigure = uifigure;
            app.HeadsetViewerUIFigure.Name = 'Headset Viewer';
            app.HeadsetViewerUIFigure.CloseRequestFcn = createCallbackFcn(app, @HeadsetViewerUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.HeadsetViewerUIFigure);
            app.EGI129Tab = uitab(app.TabGroup);
            app.EGI129Tab.Title = 'EGI-129';
            app.EGI129TextArea = uitextarea(app.EGI129Tab);
            app.EGI129TextArea.Editable = 'off';
            app.EGI129Button = uibutton(app.EGI129Tab, 'state');
            app.EGI129Button.ValueChangedFcn = createCallbackFcn(app, @EGI129ButtonValueChanged, true);
            app.EGI129Button.Text = 'Plot Headset';
            app.DSI24Tab = uitab(app.TabGroup);
            app.DSI24Tab.Title = 'DSI-24';
            app.DSI24Button = uibutton(app.DSI24Tab, 'state');
            app.DSI24Button.ValueChangedFcn = createCallbackFcn(app, @DSI24ButtonValueChanged, true);
            app.DSI24Button.Text = 'Plot Headset';
            app.DSI24TextArea = uitextarea(app.DSI24Tab);
            app.DSI24TextArea.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = headset_viewer_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HeadsetViewerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HeadsetViewerUIFigure)
        end
    end
end