classdef replayer_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Replayer                    matlab.ui.Figure
        Menu                        matlab.ui.container.Menu
        TabGroup                    matlab.ui.container.TabGroup
        ReplayerTab                 matlab.ui.container.Tab
        DataSliderLabel             matlab.ui.control.Label
        DataSlider                  matlab.ui.control.Slider
        StartSliderLabel            matlab.ui.control.Label
        StartSlider                 matlab.ui.control.Slider
        StopSliderLabel             matlab.ui.control.Label
        StopSlider                  matlab.ui.control.Slider
        CurrentIndexEditField       matlab.ui.control.NumericEditField
        CurrentIndexEditFieldLabel  matlab.ui.control.Label
        StartIndexEditField         matlab.ui.control.NumericEditField
        StartIndexEditFieldLabel    matlab.ui.control.Label
        StopIndexEditField          matlab.ui.control.NumericEditField
        StopIndexEditFieldLabel     matlab.ui.control.Label
        InformationTab              matlab.ui.container.Tab
        SettingsTab                 matlab.ui.container.Tab
        TicksKnobLabel              matlab.ui.control.Label
        TicksKnob                   matlab.ui.control.Knob
        TickTextArea                matlab.ui.control.TextArea
        PauseButton                 matlab.ui.control.StateButton
        Switch                      matlab.ui.control.Switch
    end


    properties (Access = public)
        main_application; % link to EEGsonic_app
    end

    properties (Access = private)
        % Structure containing paths
        play;
        parameters;
        information;
        loading_directory;
        data_directory;
        parameters_directory;
        replay_path;
        start_index;
        stop_index;
        current_index;
        is_loop;
        number_index;
        app_timer;
        ticks_percentage;
    end

    methods (Access = private)
        
        function number_index = get_number_index(app,data_directory)
            file_list = dir(data_directory);
            number_index = 0;
            for i = 1:length(file_list)
                value = split(file_list(i).name,".");
                value = value(1);
                try
                    value = str2num(cell2mat(value(1)));
                    if(value > number_index)
                       number_index = value; 
                    end
                catch
                    
                end
            end
        end
    
        function [major_ticks] = make_major_ticks(app) 
            max_number_ticks = app.number_index+1;
            number_ticks = round(app.ticks_percentage*max_number_ticks);
            major_ticks = round(linspace(0,app.number_index,number_ticks));
        end
        
        function set_gui(app)
            app.DataSlider.Limits = [0,app.number_index];
            app.DataSlider.Value = app.start_index;
            
            app.StartSlider.Limits = [0,app.number_index];
            app.StartSlider.Value = app.start_index;

            app.StopSlider.Limits = [0,app.number_index];
            app.StopSlider.Value = app.stop_index;
            
        end
        
        function increment_index(app)
            if(app.current_index >= app.stop_index && app.is_loop)
                app.current_index = app.start_index;
                
            elseif(app.current_index >= app.stop_index)
                app.current_index = app.stop_index; %Not moving
            else
                app.current_index = app.current_index + 1;
            end
            
            app.DataSlider.Value = app.current_index;
            
            index = app.current_index;
            app.CurrentIndexEditField.Value = index;
            save(app.replay_path,'index');
        end
        
        function load_index(app)
           index = app.current_index;
           save(app.replay_path,'index');
           app.CurrentIndexEditField.Value = index;
        end
    
        function set_ticks(app)
            major_ticks = make_major_ticks(app);
            app.DataSlider.MajorTicks = major_ticks;
            app.StartSlider.MajorTicks = major_ticks;
            app.StopSlider.MajorTicks = major_ticks;            
        end
    end

    methods (Access = public)
    
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Setup Base Directory
            base_directory = erase(mfilename('fullpath'),strcat(filesep,mfilename));
          
            % Setting up Variables
            current_data_path = strcat(filesep,'..',filesep,'data',filesep,'app_data.mat');
            app_data = load(strcat(base_directory,current_data_path));
            app.parameters = app_data.parameters;
            app.information = app_data.information;
            app.replay_path = app_data.information.replay_path;
            app.information.base_directory = base_directory;
            app.loading_directory = app.parameters.general.replay.replay_directory;
            app.data_directory = strcat(app.loading_directory,filesep,"data");
            app.parameters_directory = strcat(app.loading_directory,filesep,"parameters");
            
            % Load and set the information and parameters to be equal to those of the previous recording
            old_data_path = strcat(app.parameters_directory,filesep,'app_data.mat');
            app_data = load(old_data_path);
            app_data = app_data.app_data;
            app.parameters = app_data.parameters;
            app.information = app_data.information;
            
            % Setup the GUI
            app.number_index = get_number_index(app,app.data_directory);
            app.start_index = 0;
            app.stop_index = app.number_index;
            app.current_index = 0;
            app.is_loop = 0;
            app.ticks_percentage = app.TicksKnob.Value;
            
            app.CurrentIndexEditField.Value = 0;
            app.StartIndexEditField.Value = 0;
            app.StopIndexEditField.Value = app.stop_index;
            
            set_gui(app);
            set_ticks(app);
            
        end

        % Close request function: Replayer
        function ReplayerCloseRequest(app, event)
            app.main_application.is_replayer_running = 0;
            delete(app);
        end

        % Value changed function: PauseButton
        function PauseButtonValueChanged(app, event)
            value = app.PauseButton.Value;
            if(value == 1)
                app.play = 1;
                app.PauseButton.Text = "Play";
                % Create a timer object and tell him to execute a function every x seconds
                if(app.parameters.general.egi129.is_selected)
                  delay = app.parameters.general.egi129.data_acquisition_size;  
                end
                app.app_timer = timer('Period',delay,...
                'ExecutionMode', 'fixedSpacing', ...
                'TasksToExecute', Inf,'StartDelay',delay);
                app.app_timer.StartFcn = @(~,~)app.load_index;
                app.app_timer.TimerFcn = @(~,~)app.increment_index;
                start(app.app_timer)
            else
                app.play = 0;
                app.PauseButton.Text = "Pause";
                % Delete the timer object
                stop(app.app_timer);
                delete(app.app_timer);
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if(strcmp(value,"Loop On"))
               app.is_loop = 1; 
            else
               app.is_loop = 0;
            end
        end

        % Value changing function: DataSlider
        function DataSliderValueChanging(app, event)
            changingValue = event.Value;
            changingValue = round(changingValue); %discretizing the values
            if(changingValue < app.start_index )
               app.DataSlider.Value = app.start_index; 
            elseif(changingValue > app.stop_index)
                app.DataSlider.Value = app.stop_index;
            else
                app.DataSlider.Value = changingValue;
            end
            
            app.current_index = app.DataSlider.Value;
            app.CurrentIndexEditField.Value = app.current_index;
        end

        % Value changing function: StartSlider
        function StartSliderValueChanging(app, event)
            changingValue = event.Value;
            changingValue = round(changingValue); % discretizing the values
            if(changingValue > app.stop_index)
               app.StartSlider.Value = app.stop_index;
            elseif(changingValue > app.current_index)
                app.current_index = changingValue;
                app.DataSlider.Value = changingValue;
                app.StartSlider.Value = changingValue;
            else
                app.StartSlider.Value = changingValue;
            end
            app.start_index = app.StartSlider.Value;
            app.CurrentIndexEditField.Value = app.current_index;
            app.StartIndexEditField.Value = app.start_index;
        end

        % Value changing function: StopSlider
        function StopSliderValueChanging(app, event)
            changingValue = event.Value;
            changingValue = round(changingValue); %discretizing the value
            if(changingValue < app.start_index)
               app.StopSlider.Value = app.start_index;
            elseif(changingValue < app.current_index)
                app.current_index = changingValue;
                app.DataSlider.Value = changingValue;
                app.StopSlider.Value = changingValue;
            else
                app.StopSlider.Value = changingValue;
            end
            app.stop_index = app.StopSlider.Value; 
            app.CurrentIndexEditField.Value = app.current_index;
            app.StopIndexEditField.Value = app.stop_index;
        end

        % Value changed function: TicksKnob
        function TicksKnobValueChanged(app, event)
            value = app.TicksKnob.Value;
            app.ticks_percentage = value;
            set_ticks(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Replayer = uifigure;
            app.Replayer.Name = 'Replayer';
            app.Replayer.CloseRequestFcn = createCallbackFcn(app, @ReplayerCloseRequest, true);
            app.Menu = uimenu(app.Replayer);
            app.Menu.Text = 'Menu';
            app.TabGroup = uitabgroup(app.Replayer);
            app.ReplayerTab = uitab(app.TabGroup);
            app.ReplayerTab.Title = 'Replayer';
            app.DataSliderLabel = uilabel(app.ReplayerTab);
            app.DataSliderLabel.Text = 'Data';
            app.DataSlider = uislider(app.ReplayerTab);
            app.DataSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.DataSlider.ValueChangingFcn = createCallbackFcn(app, @DataSliderValueChanging, true);
            app.DataSlider.MinorTicks = [];
            app.StartSliderLabel = uilabel(app.ReplayerTab);
            app.StartSliderLabel.Text = 'Start';
            app.StartSlider = uislider(app.ReplayerTab);
            app.StartSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.StartSlider.ValueChangingFcn = createCallbackFcn(app, @StartSliderValueChanging, true);
            app.StartSlider.MinorTicks = [];
            app.StopSliderLabel = uilabel(app.ReplayerTab);
            app.StopSliderLabel.Text = 'Stop';
            app.StopSlider = uislider(app.ReplayerTab);
            app.StopSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.StopSlider.ValueChangingFcn = createCallbackFcn(app, @StopSliderValueChanging, true);
            app.StopSlider.MinorTicks = [];
            app.StopSlider.Value = 100;
            app.CurrentIndexEditField = uieditfield(app.ReplayerTab, 'numeric');
            app.CurrentIndexEditField.Limits = [0 Inf];
            app.CurrentIndexEditField.Editable = 'off';
            app.CurrentIndexEditFieldLabel = uilabel(app.ReplayerTab);
            app.CurrentIndexEditFieldLabel.Text = 'Current Index';
            app.StartIndexEditField = uieditfield(app.ReplayerTab, 'numeric');
            app.StartIndexEditField.Limits = [0 Inf];
            app.StartIndexEditField.Editable = 'off';
            app.StartIndexEditFieldLabel = uilabel(app.ReplayerTab);
            app.StartIndexEditFieldLabel.Text = 'Start Index';
            app.StopIndexEditField = uieditfield(app.ReplayerTab, 'numeric');
            app.StopIndexEditField.Limits = [0 Inf];
            app.StopIndexEditField.Editable = 'off';
            app.StopIndexEditFieldLabel = uilabel(app.ReplayerTab);
            app.StopIndexEditFieldLabel.Text = 'Stop Index';
            app.InformationTab = uitab(app.TabGroup);
            app.InformationTab.Title = 'Information';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.TicksKnobLabel = uilabel(app.SettingsTab);
            app.TicksKnobLabel.Text = 'Ticks';
            app.TicksKnob = uiknob(app.SettingsTab, 'continuous');
            app.TicksKnob.Limits = [0 1];
            app.TicksKnob.ValueChangedFcn = createCallbackFcn(app, @TicksKnobValueChanged, true);
            app.TicksKnob.Value = 0.5;
            app.TickTextArea = uitextarea(app.SettingsTab);
            app.TickTextArea.Editable = 'off';
            app.TickTextArea.Value = {'Reduce or increase the number of ticks in the Replayer. '; '0 means that there is no ticks and 1 means that there is one ticks per unit.'};
            app.PauseButton = uibutton(app.Replayer, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonValueChanged, true);
            app.PauseButton.Text = 'Pause';
            app.Switch = uiswitch(app.Replayer, 'slider');
            app.Switch.Items = {'Loop Off', 'Loop On'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Loop Off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = replayer_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Replayer)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Replayer)
        end
    end
end